classdef TomatoClassifierApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ResultsTable          matlab.ui.control.Table
        ExportExcelButton     matlab.ui.control.Button
        InputToTableButton    matlab.ui.control.Button
        MeanBValueDisplay     matlab.ui.control.NumericEditField
        BlueLabel             matlab.ui.control.Label
        MeanGValueDisplay     matlab.ui.control.NumericEditField
        GreenLabel            matlab.ui.control.Label
        MeanRValueDisplay     matlab.ui.control.NumericEditField
        RedLabel              matlab.ui.control.Label
        MainTitleLabel        matlab.ui.control.Label
        PengolahanCitraLabel  matlab.ui.control.Label
        StatusTextArea        matlab.ui.control.TextArea
        StatusLabel           matlab.ui.control.Label
        ResultTextArea        matlab.ui.control.TextArea
        HasilPrediksiLabel    matlab.ui.control.Label
        CitraAsliLabel        matlab.ui.control.Label
        BlueChannelLabel      matlab.ui.control.Label
        GreenChannelLabel     matlab.ui.control.Label
        RedChannelLabel       matlab.ui.control.Label
        ClassifyButton        matlab.ui.control.Button
        OpenImageButton       matlab.ui.control.Button
        BlueChannelAxes       matlab.ui.control.UIAxes
        GreenChannelAxes      matlab.ui.control.UIAxes
        RedChannelAxes        matlab.ui.control.UIAxes
        ImageAxes             matlab.ui.control.UIAxes
    end

    % Properties private untuk menyimpan data model dan gambar
    properties (Access = private)
        LoadedModel            % Akan berisi model KNN yang dimuat
        LoadedLabelNames       % Nama kelas kematangan
        LoadedImageSize        % Ukuran gambar dari training
        ParamsForNormalization % Struct untuk menyimpan parameter normalisasi
        UseNormalizationFlag   % Boolean apakah normalisasi digunakan saat training

        SelectedImagePath      % Path ke gambar yang dipilih pengguna
        OriginalImage          % Gambar asli yang dipilih pengguna
        CurrentRGBMeans        % Untuk menyimpan mean RGB gambar saat ini [R, G, B]
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusTextArea.Value = {'Memuat model KNN...'};
            app.ResultTextArea.Value = {'Pilih gambar & klasifikasi.'};
            app.ClassifyButton.Enable = 'off';
            app.InputToTableButton.Enable = 'off';
            app.ExportExcelButton.Enable = 'off';
            app.CurrentRGBMeans = [];
        
            % Inisialisasi kolom tabel dengan tipe data yang benar
            app.ResultsTable.Data = table('Size', [0, 3], ...
                                          'VariableTypes', {'double', 'double', 'double'}, ...
                                          'VariableNames', {'Red', 'Green', 'Blue'});
            try
                modelData = load('tomato_knn_model.mat'); % Pastikan file ini ada!
        
                if isfield(modelData, 'knnModel'), app.LoadedModel = modelData.knnModel; else, error('Var "knnModel" tidak ada.'); end
                if isfield(modelData, 'labelNames'), app.LoadedLabelNames = modelData.labelNames; else, error('Var "labelNames" tidak ada.'); end
                if isfield(modelData, 'imageSize'), app.LoadedImageSize = modelData.imageSize; else, error('Var "imageSize" tidak ada.'); end
                if isfield(modelData, 'useNormalization'), app.UseNormalizationFlag = modelData.useNormalization; else, app.UseNormalizationFlag = false; warning('Var "useNormalization" tidak ada, diasumsikan false.'); end
                if isfield(modelData, 'paramsForNormalization'), app.ParamsForNormalization = modelData.paramsForNormalization; else, app.ParamsForNormalization = struct(); warning('Var "paramsForNormalization" tidak ada.'); end
        
                app.StatusTextArea.Value = {'Model KNN berhasil dimuat. Aplikasi siap.'};
                disp('Model KNN berhasil dimuat.');
            catch ME_load_app
                app.StatusTextArea.Value = {'Error: Gagal memuat model KNN!'};
                app.ResultTextArea.Value = {'Model tidak termuat.'};
                disp(ME_load_app.getReport('extended', 'hyperlinks','off'));
                uiwait(errordlg(sprintf('Gagal memuat model tomato_knn_model.mat: %s\nPastikan file tersebut ada dan berisi semua variabel yang diperlukan.', ME_load_app.message), 'Error Pemuatan Model', 'modal'));
            end
        end

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            filter = {'*.jpg;*.jpeg;*.png;*.bmp', 'File Gambar (*.jpg, *.jpeg, *.png, *.bmp)'};
            [fileName, filePath] = uigetfile(filter, 'Pilih Gambar Tomat');
        
            if fileName == 0 % Jika pengguna membatalkan
                app.StatusTextArea.Value = {'Pemilihan gambar dibatalkan.'};
                return;
            end
            app.SelectedImagePath = fullfile(filePath, fileName);
        
            try
                app.OriginalImage = imread(app.SelectedImagePath);
                imshow(app.OriginalImage, 'Parent', app.ImageAxes);
                title(app.ImageAxes, 'Citra Asli'); % Judul untuk Axes gambar asli
                app.ResultTextArea.Value = {'Gambar berhasil dimuat. Klik "Klasifikasi Kematangan".'};
                app.StatusTextArea.Value = {['Gambar: ', fileName]};
        
                % Kosongkan tampilan channel dan nilai RGB sebelumnya
                cla(app.RedChannelAxes); title(app.RedChannelAxes, ''); 
                cla(app.GreenChannelAxes); title(app.GreenChannelAxes, '');
                cla(app.BlueChannelAxes); title(app.BlueChannelAxes, '');
                app.MeanRValueDisplay.Value = 0; 
                app.MeanGValueDisplay.Value = 0;
                app.MeanBValueDisplay.Value = 0;
                app.CurrentRGBMeans = []; 
        
                if ~isempty(app.LoadedModel)
                    app.ClassifyButton.Enable = 'on';
                end
                app.InputToTableButton.Enable = 'off'; % Nonaktifkan sampai ada hasil baru
        
            catch ME_img_load
                cla(app.ImageAxes); title(app.ImageAxes, 'Citra Asli');
                app.ResultTextArea.Value = {'Gagal memuat gambar. Coba gambar lain.'};
                app.StatusTextArea.Value = {'Error memuat gambar.'};
                app.ClassifyButton.Enable = 'off';
                app.MeanRValueDisplay.Value = 0;
                app.MeanGValueDisplay.Value = 0;
                app.MeanBValueDisplay.Value = 0;
                app.CurrentRGBMeans = [];
                disp(ME_img_load.getReport('extended', 'hyperlinks','off'));
                uiwait(errordlg(sprintf('Gagal memuat atau menampilkan gambar: %s', ME_img_load.message), 'Error Memuat Gambar', 'modal'));
            end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uiwait(warndlg('Pilih gambar terlebih dahulu.','Peringatan','modal'));
                return;
            end
            if isempty(app.LoadedModel)
                uiwait(errordlg('Model KNN belum dimuat.','Error Model','modal'));
                return;
            end
        
            app.StatusTextArea.Value = {'Memproses dan mengklasifikasi...'}; drawnow;
            try
                imgToProcess = app.OriginalImage;
                if size(imgToProcess,3) == 1, imgToProcess = cat(3, imgToProcess, imgToProcess, imgToProcess); end
                if size(imgToProcess,3) == 4, imgToProcess = imgToProcess(:,:,1:3); end
                imgResized = imresize(imgToProcess, app.LoadedImageSize);
        
                R_channel = imgResized(:,:,1); G_channel = imgResized(:,:,2); B_channel = imgResized(:,:,3);
                imshow(R_channel, 'Parent', app.RedChannelAxes); title(app.RedChannelAxes, ''); colormap(app.RedChannelAxes, gray);
                imshow(G_channel, 'Parent', app.GreenChannelAxes); title(app.GreenChannelAxes, ''); colormap(app.GreenChannelAxes, gray);
                imshow(B_channel, 'Parent', app.BlueChannelAxes); title(app.BlueChannelAxes, ''); colormap(app.BlueChannelAxes, gray);
        
                featuresToExtract = extractColorFeaturesTomato(imgResized); % Pastikan fungsi ini ada di path
        
                if numel(featuresToExtract) >= 6
                    app.MeanRValueDisplay.Value = round(featuresToExtract(1), 3);
                    app.MeanGValueDisplay.Value = round(featuresToExtract(3), 3);
                    app.MeanBValueDisplay.Value = round(featuresToExtract(5), 3);
                    app.CurrentRGBMeans = [featuresToExtract(1), featuresToExtract(3), featuresToExtract(5)];
                    app.InputToTableButton.Enable = 'on';
                else
                    app.MeanRValueDisplay.Value = NaN; app.MeanGValueDisplay.Value = NaN; app.MeanBValueDisplay.Value = NaN;
                    app.CurrentRGBMeans = []; app.InputToTableButton.Enable = 'off';
                    warning('Ekstraksi fitur warna tidak menghasilkan cukup nilai RGB.');
                end
        
                featuresToPredict = featuresToExtract;
                statusNormalisasi = 'Normalisasi tidak digunakan.';
                if app.UseNormalizationFlag && ~isempty(app.ParamsForNormalization) && isfield(app.ParamsForNormalization, 'minVals') && isfield(app.ParamsForNormalization, 'rangeVals')
                    min_v = app.ParamsForNormalization.minVals; range_v = app.ParamsForNormalization.rangeVals;
                    if length(min_v) == size(featuresToExtract,2) && length(range_v) == size(featuresToExtract,2)
                        range_v_safe = range_v; range_v_safe(range_v_safe==0)=1;
                        featuresToPredict = (featuresToExtract - min_v) ./ range_v_safe;
                        statusNormalisasi = 'Fitur dinormalisasi.';
                    else, warning('Dimensi param normalisasi salah.'); statusNormalisasi = 'Peringatan: Normalisasi gagal (dimensi).'; end
                elseif app.UseNormalizationFlag, warning('Flag normalisasi true tapi param tidak lengkap.'); statusNormalisasi = 'Peringatan: Normalisasi gagal (param).'; end
                app.StatusTextArea.Value = {[statusNormalisasi, ' Melakukan prediksi...']}; drawnow;
        
                predictedLabelNum = predict(app.LoadedModel, featuresToPredict);
                predictedMaturity = app.LoadedLabelNames{predictedLabelNum};
        
                app.ResultTextArea.Value = {['Prediksi: ', predictedMaturity]};
                app.StatusTextArea.Value = {'Klasifikasi selesai.'};
                title(app.ImageAxes, ['Citra Asli - Prediksi: ', predictedMaturity]);
            catch ME_classify
                app.ResultTextArea.Value = {'Error klasifikasi!'};
                app.StatusTextArea.Value = {'Error klasifikasi.'};
                app.MeanRValueDisplay.Value = NaN; app.MeanGValueDisplay.Value = NaN; app.MeanBValueDisplay.Value = NaN;
                app.InputToTableButton.Enable = 'off';
                app.CurrentRGBMeans = [];
                disp(ME_classify.getReport('extended','hyperlinks','off'));
                uiwait(errordlg(sprintf('Error saat klasifikasi: %s', ME_classify.message),'Error Klasifikasi','modal'));
            end
        end

        % Button pushed function: InputToTableButton
        function InputToTableButtonPushed(app, event)
            if ~isempty(app.CurrentRGBMeans)
                currentTableData = app.ResultsTable.Data;
                newRowValues = {round(app.CurrentRGBMeans(1),3), round(app.CurrentRGBMeans(2),3), round(app.CurrentRGBMeans(3),3)};
        
                newRowTable = cell2table(newRowValues, 'VariableNames', {'Red', 'Green', 'Blue'});
        
                if isempty(currentTableData) || width(currentTableData) == 0
                    app.ResultsTable.Data = newRowTable;
                else
                    if ~isequal(app.ResultsTable.ColumnName', {'Red';'Green';'Blue'}) && height(currentTableData) > 0
                         warning('Nama kolom tabel tidak sesuai. Menggunakan nama kolom yang ada.');
                         newRowTable = cell2table(newRowValues, 'VariableNames', app.ResultsTable.ColumnName);
                    end
                    app.ResultsTable.Data = [currentTableData; newRowTable];
                end
                app.StatusTextArea.Value = {'Data RGB ditambahkan ke tabel.'};
                app.ExportExcelButton.Enable = 'on';
            else
                app.StatusTextArea.Value = {'Tidak ada data RGB untuk ditambahkan.'};
                uiwait(warndlg('Tidak ada data RGB dari gambar saat ini untuk ditambahkan ke tabel. Lakukan klasifikasi terlebih dahulu.', 'Peringatan', 'modal'));
            end
        end

        % Button pushed function: ExportExcelButton
        function ExportExcelButtonPushed(app, event)
            tableData = app.ResultsTable.Data;
            if isempty(tableData) || height(tableData) == 0
                app.StatusTextArea.Value = {'Tidak ada data di tabel untuk diekspor.'};
                uiwait(warndlg('Tabel masih kosong, tidak ada data untuk diekspor.', 'Peringatan', 'modal'));
                return;
            end
        
            [fileName, filePath] = uiputfile('*.xlsx', 'Simpan Tabel Data RGB ke Excel', 'DataTomatRGB.xlsx');
            if fileName == 0 % Jika pengguna membatalkan
                app.StatusTextArea.Value = {'Ekspor ke Excel dibatalkan.'};
                return;
            end
            fullExcelPath = fullfile(filePath, fileName);
            try
                writetable(tableData, fullExcelPath);
                app.StatusTextArea.Value = {['Tabel berhasil diekspor ke: ', fileName]};
                uiwait(msgbox(['Data tabel berhasil diekspor ke: ', fullExcelPath], 'Ekspor Berhasil', 'modal'));
            catch ME_export
                app.StatusTextArea.Value = {'Gagal mengekspor tabel ke Excel.'};
                disp(ME_export.getReport('extended','hyperlinks','off'));
                uiwait(errordlg(sprintf('Gagal mengekspor data ke Excel: %s', ME_export.message), 'Error Ekspor', 'modal'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Gambar')
            xlabel(app.ImageAxes, 'X')
            zlabel(app.ImageAxes, 'Z')
            app.RedChannelAxes = uiaxes(app.UIFigure);
            title(app.RedChannelAxes, 'Red')
            xlabel(app.RedChannelAxes, 'X')
            ylabel(app.RedChannelAxes, 'Y')
            zlabel(app.RedChannelAxes, 'Z')
            app.GreenChannelAxes = uiaxes(app.UIFigure);
            title(app.GreenChannelAxes, 'Green')
            xlabel(app.GreenChannelAxes, 'X')
            ylabel(app.GreenChannelAxes, 'Y')
            zlabel(app.GreenChannelAxes, 'Z')
            app.BlueChannelAxes = uiaxes(app.UIFigure);
            title(app.BlueChannelAxes, 'Blue')
            xlabel(app.BlueChannelAxes, 'X')
            ylabel(app.BlueChannelAxes, 'Y')
            zlabel(app.BlueChannelAxes, 'Z')
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Pilih Gambar Tomat';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Klasifikasi Kematangan';
            app.RedChannelLabel = uilabel(app.UIFigure);
            app.RedChannelLabel.Text = 'Red :';
            app.GreenChannelLabel = uilabel(app.UIFigure);
            app.GreenChannelLabel.Text = 'Green :';
            app.BlueChannelLabel = uilabel(app.UIFigure);
            app.BlueChannelLabel.Text = 'Blue :';
            app.CitraAsliLabel = uilabel(app.UIFigure);
            app.CitraAsliLabel.Text = 'Citra Asli';
            app.HasilPrediksiLabel = uilabel(app.UIFigure);
            app.HasilPrediksiLabel.Text = 'Hasil Prediksi';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.PengolahanCitraLabel = uilabel(app.UIFigure);
            app.PengolahanCitraLabel.Text = 'Pengelolaan Citra';
            app.MainTitleLabel = uilabel(app.UIFigure);
            app.MainTitleLabel.Text = 'Klasifikasi Tingkat Kematangan Tomat';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Text = 'Red';
            app.MeanRValueDisplay = uieditfield(app.UIFigure, 'numeric');
            app.MeanRValueDisplay.Editable = 'off';
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = 'Green';
            app.MeanGValueDisplay = uieditfield(app.UIFigure, 'numeric');
            app.MeanGValueDisplay.Editable = 'off';
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Text = 'Blue';
            app.MeanBValueDisplay = uieditfield(app.UIFigure, 'numeric');
            app.MeanBValueDisplay.Editable = 'off';
            app.InputToTableButton = uibutton(app.UIFigure, 'push');
            app.InputToTableButton.ButtonPushedFcn = createCallbackFcn(app, @InputToTableButtonPushed, true);
            app.InputToTableButton.Text = 'Input Data To Excel';
            app.ExportExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportExcelButtonPushed, true);
            app.ExportExcelButton.Text = 'Export Excel';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TomatoClassifierApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end