classdef TomatoClassifierApp_Lengkap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureKlasifikasiKematanganTomat  matlab.ui.Figure
        StatusDisplayLabel        matlab.ui.control.Label
        ResultDisplayLabel        matlab.ui.control.Label
        ClassifyButton            matlab.ui.control.Button
        BlueChannelDisplayLabel   matlab.ui.control.Label
        GreenChannelDisplayLabel  matlab.ui.control.Label
        RedChannelDisplayLabel    matlab.ui.control.Label
        AvgBlueField              matlab.ui.control.NumericEditField
        MeanBTextLabel            matlab.ui.control.Label
        AvgGreenField             matlab.ui.control.NumericEditField
        MeanGTextLabel            matlab.ui.control.Label
        MeanRTextLabel            matlab.ui.control.Label
        AvgRedField               matlab.ui.control.NumericEditField
        CitraAsliLabel            matlab.ui.control.Label
        SelectImageButton         matlab.ui.control.Button
        FeatureTable              matlab.ui.control.Table
        ExportExcelButton         matlab.ui.control.Button
        InputExcelButton          matlab.ui.control.Button
        ImageAxes                 matlab.ui.control.UIAxes
        BlueChannelAxes           matlab.ui.control.UIAxes
        GreenChannelAxes          matlab.ui.control.UIAxes
        RedChannelAxes            matlab.ui.control.UIAxes
    end

    % Properties private untuk data internal aplikasi
    properties (Access = private)
        LoadedModel             % Model K-NN yang telah dilatih
        LoadedLabelNames        % Nama-nama kelas (misal: 'Mentah', 'Matang')
        LoadedImageSize         % Ukuran gambar yang digunakan saat training
        UseNormalizationFlag    % Status apakah normalisasi digunakan saat training
        ParamsForNormalization  % Parameter (min, range) untuk normalisasi
        SelectedImagePath       % Path lengkap ke gambar yang dipilih pengguna
        OriginalImage           % Data citra asli yang dipilih
        ExtractedFeatures       % Vektor fitur yang diekstrak dari gambar
        FeatureNames = {'Mean R'; 'Std R'; 'Mean G'; 'Std G'; 'Mean B'; 'Std B'; ...
                        'Mean H'; 'Std H'; 'Mean S'; 'Std S'; 'Mean V'; 'Std V'}; % Nama-nama fitur
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusDisplayLabel.Text = 'Status: Memuat model K-NN...';
            app.ResultDisplayLabel.Text = 'Hasil Prediksi: -';
            app.ClassifyButton.Enable = 'off';
            app.ExportExcelButton.Enable = 'off';

            % Inisialisasi FeatureTable
            app.FeatureTable.Data = cell(length(app.FeatureNames), 2);
            app.FeatureTable.ColumnName = {'Nama Fitur'; 'Nilai'};
            app.FeatureTable.ColumnEditable = [false false];
            app.FeatureTable.RowName = {}; 

            modelFileName = 'tomato_maturity_knn_model.mat'; % Nama file model kita
            try
                if ~isfile(modelFileName)
                    error('File model "%s" tidak ditemukan. Jalankan skrip pelatihan (trainEvaluateMultiK_KNN.m) terlebih dahulu.', modelFileName);
                end
                modelData = load(modelFileName);

                requiredVars = {'knnModel_final_to_save', 'labelNames', 'imageSize', 'useNormalization', 'paramsForNormalization'};
                for k_var_idx = 1:length(requiredVars)
                    currentVarName = requiredVars{k_var_idx};
                    if ~isfield(modelData, currentVarName)
                        error('Variabel "%s" tidak ditemukan dalam file model "%s".', currentVarName, modelFileName);
                    end
                end

                app.LoadedModel = modelData.knnModel_final_to_save;
                app.LoadedLabelNames = modelData.labelNames;
                app.LoadedImageSize = modelData.imageSize;
                app.UseNormalizationFlag = modelData.useNormalization;
                app.ParamsForNormalization = modelData.paramsForNormalization;
                app.StatusDisplayLabel.Text = 'Status: Model K-NN berhasil dimuat. Aplikasi siap.';
                disp('Model K-NN berhasil dimuat ke dalam aplikasi GUI.');
            catch ME_load_app
                app.StatusDisplayLabel.Text = 'Status: Error! Gagal memuat model K-NN!';
                app.ResultDisplayLabel.Text = 'Hasil Prediksi: Model tidak dapat dimuat.';
                disp(ME_load_app.getReport('extended', 'hyperlinks','off'));
                uiwait(errordlg(sprintf('Gagal memuat model "%s":\n%s', modelFileName, ME_load_app.message), 'Error Pemuatan Model', 'modal'));
            end
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            filter = {'*.jpg;*.jpeg;*.png;*.bmp', 'File Gambar (*.jpg, *.jpeg, *.png, *.bmp)'};
            [fileName, filePath] = uigetfile(filter, 'Pilih Citra Tomat');

            if fileName == 0
                app.StatusDisplayLabel.Text = 'Status: Pemilihan citra dibatalkan.';
                return;
            end
            app.SelectedImagePath = fullfile(filePath, fileName);

            try
                app.OriginalImage = imread(app.SelectedImagePath);
                imshow(app.OriginalImage, 'Parent', app.ImageAxes);
                app.ImageAxes.Title.String = strrep(fileName, '_', ' ');
                app.ResultDisplayLabel.Text = 'Hasil Prediksi: Klik "Klasifikasi".';
                app.StatusDisplayLabel.Text = ['Status: Citra dipilih: ', fileName];

                imgToProcess = app.OriginalImage;
                if size(imgToProcess,3) == 1
                    imgToProcess = cat(3, imgToProcess, imgToProcess, imgToProcess);
                elseif size(imgToProcess,3) == 4
                    imgToProcess = imgToProcess(:,:,1:3);
                end
                
                R_channel = imgToProcess(:,:,1);
                G_channel = imgToProcess(:,:,2);
                B_channel = imgToProcess(:,:,3);

                imshow(R_channel, 'Parent', app.RedChannelAxes); title(app.RedChannelAxes, '');
                imshow(G_channel, 'Parent', app.GreenChannelAxes); title(app.GreenChannelAxes, '');
                imshow(B_channel, 'Parent', app.BlueChannelAxes); title(app.BlueChannelAxes, '');
                
                app.AvgRedField.Value = round(mean(R_channel(:)), 1);
                app.AvgGreenField.Value = round(mean(G_channel(:)), 1);
                app.AvgBlueField.Value = round(mean(B_channel(:)), 1);

                imgResized = imresize(imgToProcess, app.LoadedImageSize);
                app.ExtractedFeatures = extractColorFeaturesTomato(imgResized);
                
                tableData = cell(length(app.FeatureNames), 2);
                for i = 1:length(app.FeatureNames)
                    tableData{i,1} = app.FeatureNames{i};
                    tableData{i,2} = round(app.ExtractedFeatures(i), 4);
                end
                app.FeatureTable.Data = tableData;
                
                app.ExportExcelButton.Enable = 'on';
                if ~isempty(app.LoadedModel), app.ClassifyButton.Enable = 'on'; end

            catch ME_img_load
                cla(app.ImageAxes); app.ImageAxes.Title.String = '';
                cla(app.RedChannelAxes); title(app.RedChannelAxes, ''); 
                cla(app.GreenChannelAxes); title(app.GreenChannelAxes, '');
                cla(app.BlueChannelAxes); title(app.BlueChannelAxes, '');
                app.AvgRedField.Value = 0; app.AvgGreenField.Value = 0; app.AvgBlueField.Value = 0;
                app.FeatureTable.Data = cell(length(app.FeatureNames), 2);
                app.ExportExcelButton.Enable = 'off';
                app.ResultDisplayLabel.Text = 'Hasil Prediksi: Gagal muat citra.';
                app.StatusDisplayLabel.Text = 'Status: Error memuat citra.';
                app.ClassifyButton.Enable = 'off';
                disp(ME_img_load.getReport('extended'));
                uiwait(errordlg(sprintf('Gagal memuat citra: %s', ME_img_load.message), 'Error Memuat Citra', 'modal'));
            end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uiwait(warndlg('Pilih citra dahulu.', 'Peringatan', 'modal')); return;
            end
            if isempty(app.LoadedModel)
                uiwait(errordlg('Model K-NN belum dimuat.', 'Error Model', 'modal')); return;
            end
            if isempty(app.ExtractedFeatures)
                uiwait(warndlg('Fitur belum diekstrak. Pilih ulang gambar.', 'Peringatan Fitur', 'modal')); return;
            end
            
            app.StatusDisplayLabel.Text = 'Status: Memproses & mengklasifikasi...'; drawnow;
            try
                featuresToPredict = app.ExtractedFeatures;
                statusNormalisasiInfo = 'Normalisasi tidak diterapkan.';
                if app.UseNormalizationFlag && ~isempty(app.ParamsForNormalization) && ...
                   isfield(app.ParamsForNormalization, 'minVals') && isfield(app.ParamsForNormalization, 'rangeVals')
                    min_v = app.ParamsForNormalization.minVals;
                    range_v = app.ParamsForNormalization.rangeVals;
                    if length(min_v) == length(featuresToPredict) && length(range_v) == length(featuresToPredict)
                        range_v_safe = range_v; range_v_safe(range_v_safe == 0) = 1;
                        featuresToPredict = (featuresToPredict - min_v) ./ range_v_safe;
                        statusNormalisasiInfo = 'Fitur dinormalisasi sebelum prediksi.';
                    else
                        statusNormalisasiInfo = 'Peringatan: Gagal normalisasi (dimensi). Prediksi dengan fitur asli.';
                        warning('Dimensi parameter normalisasi tidak sesuai. Menggunakan fitur tanpa normalisasi.');
                    end
                elseif app.UseNormalizationFlag
                    statusNormalisasiInfo = 'Peringatan: Gagal normalisasi (parameter tidak ada). Prediksi dengan fitur asli.';
                    warning('Flag normalisasi true, tetapi parameter normalisasi tidak lengkap. Menggunakan fitur tanpa normalisasi.');
                end
                
                app.StatusDisplayLabel.Text = ['Status: ', statusNormalisasiInfo, ' Melakukan prediksi...']; drawnow;
                
                predictedLabelNum = predict(app.LoadedModel, featuresToPredict);
                predictedMaturity = app.LoadedLabelNames{predictedLabelNum};
                app.ResultDisplayLabel.Text = ['Hasil Prediksi: ', predictedMaturity];
                app.StatusDisplayLabel.Text = 'Status: Klasifikasi selesai.';
                titleParts = strsplit(app.ImageAxes.Title.String, ' - Prediksi: ');
                baseTitle = titleParts{1};
                app.ImageAxes.Title.String = [baseTitle, ' - Prediksi: ', predictedMaturity];
            catch ME_classify_app
                app.ResultDisplayLabel.Text = 'Hasil Prediksi: Error klasifikasi!';
                app.StatusDisplayLabel.Text = 'Status: Error klasifikasi.';
                disp(ME_classify_app.getReport('extended'));
                uiwait(errordlg(sprintf('Error saat klasifikasi: %s', ME_classify_app.message), 'Error Klasifikasi', 'modal'));
            end
        end

        % Button pushed function: InputExcelButton
        function InputExcelButtonPushed(app, event)
            app.StatusDisplayLabel.Text = 'Status: Fungsi "Input Data To Excel" belum diimplementasikan.';
            uiwait(msgbox('Fungsi "Input Data To Excel" belum diimplementasikan.', 'Informasi', 'warn', 'modal'));
        end

        % Button pushed function: ExportExcelButton
        function ExportExcelButtonPushed(app, event)
            if isempty(app.FeatureTable.Data) || isempty(app.ExtractedFeatures)
                uiwait(warndlg('Tidak ada fitur untuk diexport.', 'Peringatan Export', 'modal')); return;
            end
            defaultFileName = 'fitur_tomat_diekstrak.xlsx';
            [fileName, filePath] = uiputfile({'*.xlsx', 'Excel Workbook (*.xlsx)'; '*.xls', 'Excel 97-2003 (*.xls)'}, ...
                                             'Simpan Fitur ke Excel', defaultFileName);
            if fileName == 0
                app.StatusDisplayLabel.Text = 'Status: Export ke Excel dibatalkan.'; return;
            end
            fullPath = fullfile(filePath, fileName);
            try
                T = table(app.FeatureNames, app.ExtractedFeatures', 'VariableNames', {'NamaFitur', 'Nilai'});
                writetable(T, fullPath);
                app.StatusDisplayLabel.Text = ['Status: Fitur berhasil diexport ke ', fileName];
                uiwait(msgbox(['Fitur berhasil diexport ke: ', fullPath], 'Export Berhasil', 'help', 'modal'));
            catch ME_export
                app.StatusDisplayLabel.Text = 'Status: Gagal export fitur ke Excel.';
                disp(ME_export.getReport('extended'));
                uiwait(errordlg(sprintf('Gagal menyimpan file Excel: %s', ME_export.message), 'Error Export Excel', 'modal'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureKlasifikasiKematanganTomat = uifigure('Visible', 'off');
            app.UIFigureKlasifikasiKematanganTomat.Name = 'UIFigure.KlasifikasiKematanganTomat';
            app.RedChannelAxes = uiaxes(app.UIFigureKlasifikasiKematanganTomat);
            app.RedChannelAxes.XTick = [];
            app.RedChannelAxes.YTick = [];
            app.GreenChannelAxes = uiaxes(app.UIFigureKlasifikasiKematanganTomat);
            app.GreenChannelAxes.XTick = [];
            app.GreenChannelAxes.YTick = [];
            app.BlueChannelAxes = uiaxes(app.UIFigureKlasifikasiKematanganTomat);
            app.BlueChannelAxes.XTick = [];
            app.BlueChannelAxes.YTick = [];
            app.ImageAxes = uiaxes(app.UIFigureKlasifikasiKematanganTomat);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.InputExcelButton = uibutton(app.UIFigureKlasifikasiKematanganTomat, 'push');
            app.InputExcelButton.ButtonPushedFcn = createCallbackFcn(app, @InputExcelButtonPushed, true);
            app.InputExcelButton.Text = 'Input Data To Excel';
            app.ExportExcelButton = uibutton(app.UIFigureKlasifikasiKematanganTomat, 'push');
            app.ExportExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportExcelButtonPushed, true);
            app.ExportExcelButton.Text = 'Export Excel';
            app.FeatureTable = uitable(app.UIFigureKlasifikasiKematanganTomat);
            app.FeatureTable.ColumnName = {'R'; 'G'; 'B'; 'Column 4'};
            app.FeatureTable.RowName = {};
            app.SelectImageButton = uibutton(app.UIFigureKlasifikasiKematanganTomat, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Pilih Citra Tomat';
            app.CitraAsliLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.CitraAsliLabel.Text = 'Citra Asli';
            app.AvgRedField = uieditfield(app.UIFigureKlasifikasiKematanganTomat, 'numeric');
            app.AvgRedField.Editable = 'off';
            app.MeanRTextLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.MeanRTextLabel.Text = 'Red';
            app.MeanGTextLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.MeanGTextLabel.Text = 'Green';
            app.AvgGreenField = uieditfield(app.UIFigureKlasifikasiKematanganTomat, 'numeric');
            app.MeanBTextLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.MeanBTextLabel.Text = 'Blue';
            app.AvgBlueField = uieditfield(app.UIFigureKlasifikasiKematanganTomat, 'numeric');
            app.RedChannelDisplayLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.RedChannelDisplayLabel.Text = 'Red';
            app.GreenChannelDisplayLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.GreenChannelDisplayLabel.Text = 'Green';
            app.BlueChannelDisplayLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.BlueChannelDisplayLabel.Text = 'Blue';
            app.ClassifyButton = uibutton(app.UIFigureKlasifikasiKematanganTomat, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Klasifikasi Kematangan';
            app.ResultDisplayLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.ResultDisplayLabel.Text = 'Hasil Prediksi: -';
            app.StatusDisplayLabel = uilabel(app.UIFigureKlasifikasiKematanganTomat);
            app.StatusDisplayLabel.Text = 'Status: Aplikasi Siap';
            % Show the figure after all components are created
            app.UIFigureKlasifikasiKematanganTomat.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TomatoClassifierApp_Lengkap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureKlasifikasiKematanganTomat)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureKlasifikasiKematanganTomat)
        end
    end
end